classdef Interaz_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        IdentificadordesonidoLabel  matlab.ui.control.Label
        IdentificadordelIddeaudienciaPanel  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        CargarButton                matlab.ui.control.Button
        GrabarwavButton             matlab.ui.control.Button
        PararButton                 matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        IdentificadordepalabraJuanPanel  matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        CargarButton_2              matlab.ui.control.Button
        IniciarSiguienteButton      matlab.ui.control.Button
        PararButton_2               matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        Puntos1y3Button             matlab.ui.control.Button
        Punto2y4Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        sx %
        pathC
    end
    
    properties (Access = public)
        tg% Description
        ng
        tc
        nc
        tgc
        ngc
        FSc
        fs=22050
        fsM=44100
        condicion=true;
        r
        lags
        valMaxWAV
        sec
        mensaje='Detecto ';
        contX=0;
        curSample;
        lastJuan;
        numOfSamples;
        nChannels;
        Block_Size;
        val;
        ref;
        total;
        Juan_mono;
        Juan1_mono;
        Juan2_mono;
        Juan3_mono;
        Juan4_mono;
        Juan5_mono;
        Juan6_mono;
        Juan7_mono;
        Juan8_mono;
        Juan9_mono;
        Juan10_mono;
        Juan11_mono;
        Juan12_mono;
        Juan13_mono;
        Juan14_mono;
        Juan15_mono;
        Juan16_mono;
        Juan17_mono;
        condicion1=true;
        
    end
    
    properties (Access = public)
        grabacion % es la señal grabada
        cargada % es la señal cargada
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarwavButton
        function GrabarwavButtonPushed(app, event)
            
            app.sx=audiorecorder(app.fs,16,1);
            
            while app.condicion
                
                
                disp('Grabando')
                recordblocking(app.sx,app.sec);
                app.grabacion=+getaudiodata(app.sx,"int16");
                app.grabacion=app.grabacion';
                
                app.tgc=0:length(app.grabacion)-1;
                app.ngc=app.tgc/app.fs;
                plot(app.UIAxes,app.ngc,app.grabacion)
                
                [app.r,app.lags]=xcorr(app.grabacion,app.cargada,'normalized');
                app.valMaxWAV=max(app.r);
                disp(app.valMaxWAV);
                
                if app.valMaxWAV>4
                    
                    app.Label.Text=app.mensaje;
                    
                end
                
            end


        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            
            if isequal(file,0)
                
                disp('User selected Cancel');
                
            else
                
                disp(['User selected ', fullfile(path,file)]);
                app.pathC=fullfile(path,file);
                
                [app.cargada,app.FSc]= audioread(app.pathC);
                app.cargada=app.cargada';
                app.cargada=normalizar(app.cargada);
                app.sec=length(app.cargada)/app.FSc;
                app.tc=0:length(app.cargada)-1;
                app.nc=app.tc/app.FSc;
                assignin("base","Nc",app.nc);
                
                
            end


        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.condicion=false;
        end

        % Button pushed function: CargarButton_2
        function CargarButton_2Pushed(app, event)
            [Juan0,J0fs] = audioread('Juanito.wav');
            [Juan1,J1fs] = audioread('Juanito1.wav');
            [Juan2,J2fs] = audioread('Juanito2.wav');
            [Juan3,J3fs] = audioread('Juanito3.wav');
            [Juan4,J4fs] = audioread('Juanito4.wav');
            [Juan5,J5fs] = audioread('Juanito5.wav');
            [Juan6,J6fs] = audioread('Juanito6.wav');
            [Juan7,J7fs] = audioread('Juanito7.wav');
            [Juan8,J8fs] = audioread('Juanito8.wav');
            [Juan9,J9fs] = audioread('Juanito9.wav');
            [Juan10,J10fs] = audioread('Juanito10.wav');
            [Juan11,J11fs] = audioread('Juanito11.wav');
            [Juan12,J12fs] = audioread('Juanito12.wav');
            [Juan13,J13fs] = audioread('Juanito13.wav');
            [Juan14,J14fs] = audioread('Juanito14.wav');
            [Juan15,J15fs] = audioread('Juanito15.wav');
            [Juan16,J16fs] = audioread('Juanito16.wav');
            [Juan17,J17fs] = audioread('Juanito17.wav');
            
            app.Juan_mono = sum(Juan0,2);
            app.Juan_mono=normalizar(app.Juan_mono);
            app.Juan1_mono = sum(Juan1,2);
            app.Juan1_mono=normalizar(app.Juan1_mono);
            app.Juan2_mono = sum(Juan2,2);
            app.Juan2_mono=normalizar(app.Juan2_mono);
            app.Juan3_mono = sum(Juan3,2);
            app.Juan3_mono=normalizar(app.Juan3_mono);
            app.Juan4_mono = sum(Juan4,2);
            app.Juan4_mono=normalizar(app.Juan4_mono);
            app.Juan5_mono = sum(Juan5,2);
            app.Juan5_mono=normalizar(app.Juan5_mono);
            app.Juan6_mono = sum(Juan6,2);
            app.Juan6_mono=normalizar(app.Juan6_mono);
            app.Juan7_mono = sum(Juan7,2);
            app.Juan7_mono=normalizar(app.Juan7_mono);
            app.Juan8_mono = sum(Juan8,2);
            app.Juan8_mono=normalizar(app.Juan8_mono);
            app.Juan9_mono = sum(Juan9,2);
            app.Juan9_mono=normalizar(app.Juan9_mono);
            app.Juan10_mono = sum(Juan10,2);
            app.Juan10_mono=normalizar(app.Juan10_mono);
            app.Juan11_mono = sum(Juan11,2);
            app.Juan11_mono=normalizar(app.Juan11_mono);
            app.Juan12_mono = sum(Juan12,2);
            app.Juan12_mono=normalizar(app.Juan12_mono);
            app.Juan13_mono = sum(Juan13,2);
            app.Juan13_mono=normalizar(app.Juan13_mono);
            app.Juan14_mono = sum(Juan14,2);
            app.Juan14_mono=normalizar(app.Juan14_mono);
            app.Juan15_mono = sum(Juan15,2);
            app.Juan15_mono=normalizar(app.Juan15_mono);
            app.Juan16_mono = sum(Juan16,2);
            app.Juan16_mono=normalizar(app.Juan16_mono);
            app.Juan17_mono = sum(Juan17,2);
            app.Juan17_mono=normalizar(app.Juan17_mono);
            
            info = audioinfo('juan_salvador_gaviota.mp3');%%se obtiene iformaciond el audio "juan salvador gaviota"
            app.fsM= info.SampleRate;
            app.numOfSamples=(info.TotalSamples); %%se separa en los sapmples 
            app.nChannels=(info.NumChannels);%%y se obtiene numero de canales
            app.curSample=1;
            app.Block_Size =25000;%%tamaño para el bloque de influencia
%%inicializa las variables para el display en consola
            app.val = 0;
            app.ref=80;
            app.total =1;
            app.lastJuan = [[0,0]];
        end

        % Button pushed function: IniciarSiguienteButton
        function IniciarSiguienteButtonPushed(app, event)
            app.condicion1=true;
            while(app.curSample<=app.numOfSamples&&app.condicion1)
    clc;
    disp(app.lastJuan); %%imprime el juan actual 
    N1=app.curSample;
    N2=app.curSample+app.Block_Size-1;
    disp('Leyendo :v')
    disp(N1/app.fsM);
    disp(app.val);
    disp(app.total-1);
    if(N2>app.numOfSamples)
        N2=app.numOfSamples;
    end
    blockdata = audioread('juan_salvador_gaviota.mp3', [N1,N2]); %Crea el bloque donde se almacena "juan salvador gaviota"
    
    
    plot(app.UIAxes_2,blockdata);
   
    
    blockdata_mono = sum(blockdata,2);
    blockdata_mono = normalizar(blockdata_mono);
    
    val0=normalValueCor(blockdata_mono,app.Juan_mono);
    val1=normalValueCor(blockdata_mono,app.Juan1_mono); %% Correlación entre los audios de "Juan" y el audiolibro en dicho bloque.
    val2=normalValueCor(blockdata_mono,app.Juan2_mono);
    val3=normalValueCor(blockdata_mono,app.Juan3_mono);
    val4=normalValueCor(blockdata_mono,app.Juan4_mono);
    val5=normalValueCor(blockdata_mono,app.Juan5_mono);
    val6=normalValueCor(blockdata_mono,app.Juan6_mono);
    val7=normalValueCor(blockdata_mono,app.Juan7_mono);
    val8=normalValueCor(blockdata_mono,app.Juan8_mono);
    val9=normalValueCor(blockdata_mono,app.Juan9_mono);
    val10=normalValueCor(blockdata_mono,app.Juan10_mono);
    val11=normalValueCor(blockdata_mono,app.Juan11_mono);
    val12=normalValueCor(blockdata_mono,app.Juan12_mono);
    val13=normalValueCor(blockdata_mono,app.Juan13_mono);
    val14=normalValueCor(blockdata_mono,app.Juan14_mono);
    val15=normalValueCor(blockdata_mono,app.Juan15_mono);
    val16=normalValueCor(blockdata_mono,app.Juan16_mono);
    val17=normalValueCor(blockdata_mono,app.Juan17_mono);
    
  app.val = max([val0,val1,val2,val3,val4,val5,val6,val7,val8,val9,val10,val11,val12,val13,val14,val15,val16,val17]);
   if((app.val>app.ref)&&(((N1/app.fsM)-app.lastJuan(app.total,1))>app.Block_Size/app.fsM || N1<app.Block_Size))  %%Si se encuentra similitud en alguna parte del bloque se aumenta en +1 el ultimo Juan contado
       app.lastJuan(app.total+1,1) = N1/app.fsM;
       app.lastJuan(app.total+1,2) = app.val;
       app.Label_2.Text="Juan detectado en el segundo: "+app.lastJuan(app.total+1,1);
       
       sound(blockdata,app.fsM);
       app.total = app.total +1;  %%Total de Juanes encontrados
       app.condicion1=false;
   end           
    
    app.curSample=app.curSample+(app.Block_Size/2);    %si no se deja el desplazamiento en la octaba parte del bloque no detecta ni el primer Juan
    drawnow;
    
end
        end

        % Button pushed function: PararButton_2
        function PararButton_2Pushed(app, event)
            app.condicion1=false;
            
        end

        % Button pushed function: Puntos1y3Button
        function Puntos1y3ButtonPushed(app, event)
            clear
            clc
            [f,Fs]= audioread('ID_audiencia.wav');

            f=f';
            Ts=1/Fs;
            L=length(f);
            t=(0:(L-1))*Ts;

            t1=(t>=0.0518821 & t<0.122489);
            t2=(t>=1.0009070 & t<1.09977324);
            t3=(t>=1.9777778 & t<2.0798186);
            t4=(t>=2.9578231 & t<3.0566893);
            t5=(t>=3.9346939 & t<4.0331066);
            t6=(t>=4.9115646 & t<5.4058957);

            tramo1=((0.1612*sin(2*pi*1020*t)+0.03058*sin(2*pi*3059*t)+0.01131*sin(2*pi*5098*t))).*t1;
            tramo2=((0.1346*sin(2*pi*1021*t)+0.03925*sin(2*pi*3063*t)+0.0179*sin(2*pi*5106*t))).*t2;
            tramo3=((0.1058*sin(2*pi*1024*t)+0.04294*sin(2*pi*3062*t)+0.01539*sin(2*pi*5100*t))).*t3;
            tramo4=((0.1263*sin(2*pi*1021*t)+0.04306*sin(2*pi*3063*t)+0.01697*sin(2*pi*5106*t))).*t4;
            tramo5=((0.08959*sin(2*pi*1026*t))).*t5;
            tramo6=((0.09958*sin(2*pi*1021*t))).*t6;

            f1=tramo1+tramo2+tramo3+tramo4+tramo5+tramo6;
            subplot(3,1,1)
            plot(t,f1)
            sound(f1,Fs)
            xlabel("Segundos")
            ylabel("Volts")
            
            subplot(3,1,2)
            plot(t,f)
            xlabel("Segundos")
            ylabel("Volts")

            subplot(3,1,3)
            [r,lags]=xcorr(f,f1,'normalized');
            plot(lags,r)
            xlabel("Lag")
            ylabel("Magnitud de Correlación")

        end

        % Button pushed function: Punto2y4Button
        function Punto2y4ButtonPushed(app, event)
            clear
            clc
            [f,Fs]= audioread('Sputnik_beep.mp3');

            f=sum(f,2);
            f=f';
            Ts=1/Fs;
            L=length(f);
            t=(0:(L-1))*Ts;
            x=f(1,:);


            t1=(t>=2882*Ts & t<9993*Ts);
            t2=(t>=33460*Ts & t<43290*Ts);
            t3=(t>=70990*Ts & t<79680*Ts);
            t4=(t>=107800*Ts & t<116300*Ts);
            t5=(t>=142200*Ts & t<152000*Ts);
            t6=(t>=176300*Ts & t<185800*Ts);

            tramo1=((0.3277*sin(2*pi*1060*t))).*t1;
            tramo2=((0.641*sin(2*pi*1064*t))).*t2;
            tramo3=((1.047*sin(2*pi*1060*t))).*t3;
            tramo4=((0.9886*sin(2*pi*1056*t))).*t4;
            tramo5=((1.114*sin(2*pi*1063*t))).*t5;
            tramo6=((0.5923*sin(2*pi*1061*t))).*t6;

            f1=tramo1+tramo2+tramo3+tramo4+tramo5+tramo6;


            subplot(3,1,1)
            plot(t,f1)
            sound(f1,Fs)
            xlabel("Segundos")
            ylabel("Volts")

            subplot(3,1,2)

            plot(t,f)
            xlabel("Segundos")
            ylabel("Volts")

            subplot(3,1,3)
            [r,lags]=xcorr(x,f1,'normalized');
            plot(lags,r)
            xlabel("Lag")
            ylabel("Magnitud de Correlación")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.IdentificadordesonidoLabel = uilabel(app.UIFigure);
            app.IdentificadordesonidoLabel.Text = 'Identificador de sonido';
            app.IdentificadordelIddeaudienciaPanel = uipanel(app.UIFigure);
            app.IdentificadordelIddeaudienciaPanel.Title = 'Identificador del Id de audiencia.';
            app.UIAxes = uiaxes(app.IdentificadordelIddeaudienciaPanel);
            title(app.UIAxes, {'Grabación en tiempo real'; ''})
            xlabel(app.UIAxes, {'Segundos'; ''})
            ylabel(app.UIAxes, 'Volts')
            app.CargarButton = uibutton(app.IdentificadordelIddeaudienciaPanel, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.GrabarwavButton = uibutton(app.IdentificadordelIddeaudienciaPanel, 'push');
            app.GrabarwavButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarwavButtonPushed, true);
            app.GrabarwavButton.Text = ' Grabar wav';
            app.PararButton = uibutton(app.IdentificadordelIddeaudienciaPanel, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.Label = uilabel(app.IdentificadordelIddeaudienciaPanel);
            app.Label.Text = '';
            app.IdentificadordepalabraJuanPanel = uipanel(app.UIFigure);
            app.IdentificadordepalabraJuanPanel.Title = 'Identificador de palabra: "Juan".';
            app.UIAxes_2 = uiaxes(app.IdentificadordepalabraJuanPanel);
            title(app.UIAxes_2, 'Gráfico de bloque')
            xlabel(app.UIAxes_2, {'Segundos'; ''})
            ylabel(app.UIAxes_2, 'Volts')
            app.CargarButton_2 = uibutton(app.IdentificadordepalabraJuanPanel, 'push');
            app.CargarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarButton_2Pushed, true);
            app.CargarButton_2.Text = 'Cargar';
            app.IniciarSiguienteButton = uibutton(app.IdentificadordepalabraJuanPanel, 'push');
            app.IniciarSiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarSiguienteButtonPushed, true);
            app.IniciarSiguienteButton.Text = 'Iniciar/Siguiente';
            app.PararButton_2 = uibutton(app.IdentificadordepalabraJuanPanel, 'push');
            app.PararButton_2.ButtonPushedFcn = createCallbackFcn(app, @PararButton_2Pushed, true);
            app.PararButton_2.Text = 'Parar';
            app.Label_2 = uilabel(app.IdentificadordepalabraJuanPanel);
            app.Label_2.Text = '';
            app.Puntos1y3Button = uibutton(app.UIFigure, 'push');
            app.Puntos1y3Button.ButtonPushedFcn = createCallbackFcn(app, @Puntos1y3ButtonPushed, true);
            app.Puntos1y3Button.Text = 'Puntos 1 y 3';
            app.Punto2y4Button = uibutton(app.UIFigure, 'push');
            app.Punto2y4Button.ButtonPushedFcn = createCallbackFcn(app, @Punto2y4ButtonPushed, true);
            app.Punto2y4Button.Text = 'Punto 2 y 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interaz_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end